classdef preprocessing_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BrowseButton                   matlab.ui.control.Button
        FileEditFieldLabel             matlab.ui.control.Label
        FileEditField                  matlab.ui.control.EditField
        ParametersPanel                matlab.ui.container.Panel
        ClassDropDownLabel             matlab.ui.control.Label
        ClassDropDown                  matlab.ui.control.DropDown
        ShiftinginmsDropDownLabel      matlab.ui.control.Label
        ShiftinginmsDropDown           matlab.ui.control.DropDown
        WindowSizeinmsDropDownLabel    matlab.ui.control.Label
        WindowSizeinmsDropDown         matlab.ui.control.DropDown
        ThreholdcriteriaDropDownLabel  matlab.ui.control.Label
        ThreholdcriteriaDropDown       matlab.ui.control.DropDown
        ThresholdValueEditFieldLabel   matlab.ui.control.Label
        ThresholdValueEditField        matlab.ui.control.NumericEditField
        PreprocessthesignalButton      matlab.ui.control.Button
        OutputHandlerPanel             matlab.ui.container.Panel
        NoofeventsEditFieldLabel       matlab.ui.control.Label
        NoofeventsEditField            matlab.ui.control.NumericEditField
        UpdatemasterdatasheetButton    matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        SaveConcatenatedOutputButton   matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        OriginalSignalDetrendDataPointsLabel  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        LowpassFilterPanel             matlab.ui.container.Panel
        CutoffFrequencyHzEditFieldLabel  matlab.ui.control.Label
        CutoffFrequencyHzEditField     matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel      matlab.ui.control.Label
        FilterOrderEditField           matlab.ui.control.NumericEditField
        PlotSensorSpecificSignalandItsFilteredOPButton  matlab.ui.control.Button
        ChooseMethodButtonGroup        matlab.ui.container.ButtonGroup
        AutomaticButton                matlab.ui.control.RadioButton
        ManualButton                   matlab.ui.control.RadioButton
        SignalFetchingButtonGroup      matlab.ui.container.ButtonGroup
        FetchFullSignalButton          matlab.ui.control.RadioButton
        CropfromrawsignalButton        matlab.ui.control.RadioButton
        FetchButton                    matlab.ui.control.Button
        ChooseSensorLabel              matlab.ui.control.Label
        ChooseSensorDropDown           matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        t % Description
        start_time % Description
        event_id % Description
        ft_signal % Description
        end_time % Description
        
    end
    

    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)

            cla(app.UIAxes);
            cla(app.UIAxes_2);
            [file,path] = uigetfile('*.*');
            raw = load(fullfile(path,file));
            raw_det=detrend(raw);
            app.FileEditField.Value=fullfile(path,file);            
            plot(app.UIAxes,raw_det);
            axis(app.UIAxes, 'tight');
            app.UITable.Data= raw_det;
            
        end

        % Value changed function: FileEditField
        function FileEditFieldValueChanged(app, event)
            value = app.FileEditField.Value;
            
        end

        % Callback function
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
        end

        % Value changed function: ChooseSensorDropDown
        function ChooseSensorDropDownValueChanged(app, event)
            value = app.ChooseSensorDropDown.Value;
            
        end

        % Value changed function: NoofeventsEditField
        function NoofeventsEditFieldValueChanged(app, event)
            value = app.NoofeventsEditField.Value;
            
        end

        % Button pushed function: UpdatemasterdatasheetButton
        function UpdatemasterdatasheetButtonPushed(app, event)
     [file,path] = uigetfile('*.*');
     Fname=fullfile(path,file);
            if app.ManualButton.Value==1
                file_nm=app.FileEditField.Value;
                split_name=split(file_nm,'\');
                file_name=split_name(6);
                eid=app.event_id;
                evntid=string(eid);
                s_time=app.start_time;
                stym=string(s_time);
                e_time=app.end_time;
                etym=string(e_time);
                th_c='N/A';
                th_v='N/A';
                ws=e_time-s_time;
                wsize=string(ws);
                sl='N/A';
                cl=app.ClassDropDown.Value;
                dd=[file_name evntid stym etym th_c th_v wsize sl cl];
                xx=cellstr(dd);
                xlsappend(Fname,xx);
                
            else
            th= app.ThreholdcriteriaDropDown.Value;
            thval=app.ThresholdValueEditField.Value;
            winsize=app.WindowSizeinmsDropDown.Value;
            clss=app.ClassDropDown.Value;
            nm=split(app.FileEditField.Value,"\");
            name=nm(end,:);
            dd=app.t.data;
            dd=cellstr(dd);
            xlsappend(Fname,dd);
            
     end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
                
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            app.FileEditField.Value=('');
            app.NoofeventsEditField.Value=0;
            app.CutoffFrequencyHzEditField.Value=0;
            app.ThresholdValueEditField.Value=0;
            app.FilterOrderEditField.Value=0;
            app.UITable.Data=[];

        end

        % Button pushed function: 
        % PlotSensorSpecificSignalandItsFilteredOPButton
        function PlotSensorSpecificSignalandItsFilteredOPButtonPushed(app, event)
            if app.ManualButton.Value==1
                if app.ChooseSensorDropDown.Value == 'Sensor 1'
                    manual_data=app.UITable.Data(:,1);
                    fs=1000;
                    d=designfilt('lowpassfir','FilterOrder',app.FilterOrderEditField.Value,'CutoffFrequency',app.CutoffFrequencyHzEditField.Value,'SampleRate',fs);
                    f_data=filtfilt(d,manual_data);
                    figure(1),plot(manual_data);
                    hold on;
                    plot(f_data);
                    [x,y]=getpts;
                    start_time=floor(x(1));
                    end_time=floor(x(2));
                    app.start_time=start_time./1000;
                    app.end_time=end_time./1000;
                    app.event_id=start_time;
                    range=end_time-start_time;
                    cropped_data=manual_data(start_time:end_time);
                    plot(app.UIAxes,cropped_data);
                    close figure 1;
                    fs=1000;
                    d=designfilt('lowpassfir','FilterOrder',app.FilterOrderEditField.Value,'CutoffFrequency',app.CutoffFrequencyHzEditField.Value,'SampleRate',fs);
                    f_data=filtfilt(d,cropped_data);
                    plot(app.UIAxes_2,f_data,'R'); 
                    axis(app.UIAxes_2, 'tight');
                    title(app.UIAxes_2,'Filtered output','FontWeight','bold');
                    n=split(app.FileEditField.Value,"\");
                    name=char(n(end,1));
                    conpath='D:\event\concatenated signal\';
                    mkdir(conpath);
                    confile_1=strcat(conpath,name,'_sensor1_CROPPED(filtered)_',num2str(x(1)),'_to_',num2str(x(2)),'.mat');
                    save(confile_1,'f_data');
                    confile_2=strcat(conpath,name,'_sensor1_CROPPED(RawData)_',num2str(x(1)),'_to_',num2str(x(2)),'.mat');
                    save(confile_2,'cropped_data')
                elseif app.ChooseSensorDropDown.Value == 'Sensor 2'
                    manual_data=app.UITable.Data(:,2);
                    fs=1000;
                    d=designfilt('lowpassfir','FilterOrder',app.FilterOrderEditField.Value,'CutoffFrequency',app.CutoffFrequencyHzEditField.Value,'SampleRate',fs);
                    f_data=filtfilt(d,manual_data);
                    figure(1),plot(manual_data);
                    hold on;
                    plot(f_data);
                    [x,y]=getpts;
                    start_time=floor(x(1));
                    end_time=floor(x(2));
                    app.start_time=start_time./1000;
                    app.end_time=end_time./1000;
                    app.event_id=start_time;
                    range=end_time-start_time;
                    cropped_data=manual_data(start_time:end_time);
                    plot(app.UIAxes,cropped_data);
                    close figure 1;
                    fs=1000;
                    d=designfilt('lowpassfir','FilterOrder',app.FilterOrderEditField.Value,'CutoffFrequency',app.CutoffFrequencyHzEditField.Value,'SampleRate',fs);
                    f_data=filtfilt(d,cropped_data);
                    plot(app.UIAxes_2,f_data,'R'); 
                    axis(app.UIAxes_2, 'tight');
                    title(app.UIAxes_2,'Filtered output','FontWeight','bold');
                    n=split(app.FileEditField.Value,"\");
                    name=char(n(end,1));
                    conpath='D:\event\concatenated signal\';
                    mkdir(conpath);
                    confile_1=strcat(conpath,name,'_sensor2_CROPPED(filtered)_',num2str(x(1)),'_to_',num2str(x(2)),'.mat');
                    save(confile_1,'f_data');
                    confile_2=strcat(conpath,name,'_sensor2_CROPPED(RawData)_',num2str(x(1)),'_to_',num2str(x(2)),'.mat');
                    save(confile_2,'cropped_data')                 
                elseif app.ChooseSensorDropDown.Value == 'Sensor 3'
                    manual_data=app.UITable.Data(:,3);
                    fs=1000;
                    d=designfilt('lowpassfir','FilterOrder',app.FilterOrderEditField.Value,'CutoffFrequency',app.CutoffFrequencyHzEditField.Value,'SampleRate',fs);
                    f_data=filtfilt(d,manual_data);
                    figure(1),plot(manual_data);
                    hold on;
                    plot(f_data);
                    [x,y]=getpts;
                    start_time=floor(x(1));
                    end_time=floor(x(2));
                    app.start_time=start_time./1000;
                    app.end_time=end_time./1000;
                    app.event_id=start_time;
                    range=end_time-start_time;
                    cropped_data=manual_data(start_time:end_time);
                    plot(app.UIAxes,cropped_data);
                    close figure 1;
                    fs=1000;
                    d=designfilt('lowpassfir','FilterOrder',app.FilterOrderEditField.Value,'CutoffFrequency',app.CutoffFrequencyHzEditField.Value,'SampleRate',fs);
                    f_data=filtfilt(d,cropped_data);
                    plot(app.UIAxes_2,f_data,'R'); 
                    axis(app.UIAxes_2, 'tight');
                    title(app.UIAxes_2,'Filtered output','FontWeight','bold');
                    n=split(app.FileEditField.Value,"\");
                    name=char(n(end,1));
                    conpath='D:\event\concatenated signal\';
                    mkdir(conpath);
                    confile_1=strcat(conpath,name,'_sensor3_CROPPED(filtered)_',num2str(x(1)),'_to_',num2str(x(2)),'.mat');
                    save(confile_1,'f_data');
                    confile_2=strcat(conpath,name,'_sensor3_CROPPED(RawData)_',num2str(x(1)),'_to_',num2str(x(2)),'.mat');
                    save(confile_2,'cropped_data')                   
                elseif app.ChooseSensorDropDown.Value == 'Sensor 4'
                    manual_data=app.UITable.Data(:,4);
                    fs=1000;
                    d=designfilt('lowpassfir','FilterOrder',app.FilterOrderEditField.Value,'CutoffFrequency',app.CutoffFrequencyHzEditField.Value,'SampleRate',fs);
                    f_data=filtfilt(d,manual_data);
                    figure(1),plot(manual_data);
                    hold on;
                    plot(f_data);
                    [x,y]=getpts;
                    start_time=floor(x(1));
                    end_time=floor(x(2));
                    app.start_time=start_time./1000;
                    app.end_time=end_time./1000;
                    app.event_id=start_time;
                    range=end_time-start_time;
                    cropped_data=manual_data(start_time:end_time);
                    plot(app.UIAxes,cropped_data);
                    close figure 1;
                    fs=1000;
                    d=designfilt('lowpassfir','FilterOrder',app.FilterOrderEditField.Value,'CutoffFrequency',app.CutoffFrequencyHzEditField.Value,'SampleRate',fs);
                    f_data=filtfilt(d,cropped_data);
                    plot(app.UIAxes_2,f_data,'R'); 
                    axis(app.UIAxes_2, 'tight');
                    title(app.UIAxes_2,'Filtered output','FontWeight','bold');
                    n=split(app.FileEditField.Value,"\");
                    name=char(n(end,1));
                    conpath='D:\event\concatenated signal\';
                    mkdir(conpath);
                    confile_1=strcat(conpath,name,'_sensor4_CROPPED(filtered)_',num2str(x(1)),'_to_',num2str(x(2)),'.mat');
                    save(confile_1,'f_data');
                    confile_2=strcat(conpath,name,'_sensor4_CROPPED(RawData)_',num2str(x(1)),'_to_',num2str(x(2)),'.mat');
                    save(confile_2,'cropped_data')                    
                elseif app.ChooseSensorDropDown.Value == 'Sensor 5'
                    manual_data=app.UITable.Data(:,5);
                    fs=1000;
                    d=designfilt('lowpassfir','FilterOrder',app.FilterOrderEditField.Value,'CutoffFrequency',app.CutoffFrequencyHzEditField.Value,'SampleRate',fs);
                    f_data=filtfilt(d,manual_data);
                    figure(1),plot(manual_data);
                    hold on;
                    plot(f_data);
                    [x,y]=getpts;
                    start_time=floor(x(1));
                    end_time=floor(x(2));
                    app.start_time=start_time./1000;
                    app.end_time=end_time./1000;
                    app.event_id=start_time;
                    range=end_time-start_time;
                    cropped_data=manual_data(start_time:end_time);
                    plot(app.UIAxes,cropped_data);
                    close figure 1;
                    fs=1000;
                    d=designfilt('lowpassfir','FilterOrder',app.FilterOrderEditField.Value,'CutoffFrequency',app.CutoffFrequencyHzEditField.Value,'SampleRate',fs);
                    f_data=filtfilt(d,cropped_data);
                    plot(app.UIAxes_2,f_data,'R'); 
                    axis(app.UIAxes_2, 'tight');
                    title(app.UIAxes_2,'Filtered output','FontWeight','bold');
                    n=split(app.FileEditField.Value,"\");
                    name=char(n(end,1));
                    conpath='D:\event\concatenated signal\';
                    mkdir(conpath);
                    confile_1=strcat(conpath,name,'_sensor5_CROPPED(filtered)_',num2str(x(1)),'_to_',num2str(x(2)),'.mat');
                    save(confile_1,'f_data');
                    confile_2=strcat(conpath,name,'_sensor5_CROPPED(RawData)_',num2str(x(1)),'_to_',num2str(x(2)),'.mat');
                    save(confile_2,'cropped_data')                    
                elseif app.ChooseSensorDropDown.Value == 'Sensor 6'
                    manual_data=app.UITable.Data(:,6);
                    fs=1000;
                    d=designfilt('lowpassfir','FilterOrder',app.FilterOrderEditField.Value,'CutoffFrequency',app.CutoffFrequencyHzEditField.Value,'SampleRate',fs);
                    f_data=filtfilt(d,manual_data);
                    figure(1),plot(manual_data);
                    hold on;
                    plot(f_data);
                    [x,y]=getpts;
                    start_time=floor(x(1));
                    end_time=floor(x(2));
                    app.start_time=start_time./1000;
                    app.end_time=end_time./1000;
                    app.event_id=start_time;
                    range=end_time-start_time;
                    cropped_data=manual_data(start_time:end_time);
                    plot(app.UIAxes,cropped_data);
                    close figure 1;
                    fs=1000;
                    d=designfilt('lowpassfir','FilterOrder',app.FilterOrderEditField.Value,'CutoffFrequency',app.CutoffFrequencyHzEditField.Value,'SampleRate',fs);
                    f_data=filtfilt(d,cropped_data);
                    plot(app.UIAxes_2,f_data,'R'); 
                    axis(app.UIAxes_2, 'tight');
                    title(app.UIAxes_2,'Filtered output','FontWeight','bold');
                    n=split(app.FileEditField.Value,"\");
                    name=char(n(end,1));
                    conpath='D:\event\concatenated signal\';
                    mkdir(conpath);
                    confile_1=strcat(conpath,name,'_sensor6_CROPPED(filtered)_',num2str(x(1)),'_to_',num2str(x(2)),'.mat');
                    save(confile_1,'f_data');
                    confile_2=strcat(conpath,name,'_sensor6_CROPPED(RawData)_',num2str(x(1)),'_to_',num2str(x(2)),'.mat');
                    save(confile_2,'cropped_data')                    
                elseif app.ChooseSensorDropDown.Value == 'Sensor 7'
                    manual_data=app.UITable.Data(:,7);
                    fs=1000;
                    d=designfilt('lowpassfir','FilterOrder',app.FilterOrderEditField.Value,'CutoffFrequency',app.CutoffFrequencyHzEditField.Value,'SampleRate',fs);
                    f_data=filtfilt(d,manual_data);
                    figure(1),plot(manual_data);
                    hold on;
                    plot(f_data);
                    [x,y]=getpts;
                    start_time=floor(x(1));
                    end_time=floor(x(2));
                    app.start_time=start_time./1000;
                    app.end_time=end_time./1000;
                    app.event_id=start_time;
                    range=end_time-start_time;
                    cropped_data=manual_data(start_time:end_time);
                    plot(app.UIAxes,cropped_data);
                    close figure 1;
                    fs=1000;
                    d=designfilt('lowpassfir','FilterOrder',app.FilterOrderEditField.Value,'CutoffFrequency',app.CutoffFrequencyHzEditField.Value,'SampleRate',fs);
                    f_data=filtfilt(d,cropped_data);
                    plot(app.UIAxes_2,f_data,'R'); 
                    axis(app.UIAxes_2, 'tight');
                    title(app.UIAxes_2,'Filtered output','FontWeight','bold');
                    n=split(app.FileEditField.Value,"\");
                    name=char(n(end,1));
                    conpath='D:\event\concatenated signal\';
                    mkdir(conpath);
                    confile_1=strcat(conpath,name,'_sensor7_CROPPED(filtered)_',num2str(x(1)),'_to_',num2str(x(2)),'.mat');
                    save(confile_1,'f_data');
                    confile_2=strcat(conpath,name,'_sensor7_CROPPED(RawData)_',num2str(x(1)),'_to_',num2str(x(2)),'.mat');
                    save(confile_2,'cropped_data')                    
                else app.ChooseSensorDropDown.Value == 'Sensor 8'
                    manual_data=app.UITable.Data(:,8);
                    fs=1000;
                    d=designfilt('lowpassfir','FilterOrder',app.FilterOrderEditField.Value,'CutoffFrequency',app.CutoffFrequencyHzEditField.Value,'SampleRate',fs);
                    f_data=filtfilt(d,manual_data);
                    figure(1),plot(manual_data);
                    hold on;
                    plot(f_data), axis tight;
                    [x,y]=getpts;
                    start_time=floor(x(1));
                    end_time=floor(x(2));
                    app.start_time=start_time./1000;
                    app.end_time=end_time./1000;
                    app.event_id=start_time;
                    range=end_time-start_time;
                    cropped_data=manual_data(start_time:end_time);
                    plot(app.UIAxes,cropped_data);
                    close figure 1;
                    fs=1000;
                    d=designfilt('lowpassfir','FilterOrder',app.FilterOrderEditField.Value,'CutoffFrequency',app.CutoffFrequencyHzEditField.Value,'SampleRate',fs);
                    f_data=filtfilt(d,cropped_data);
                    plot(app.UIAxes_2,f_data,'R'); 
                    axis(app.UIAxes_2, 'tight');
                    title(app.UIAxes_2,'Filtered output','FontWeight','bold');
                    n=split(app.FileEditField.Value,"\");
                    name=char(n(end,1));
                    conpath='D:\event\concatenated signal\';
                    mkdir(conpath);
                    confile_1=strcat(conpath,name,'_sensor8_CROPPED(filtered)_',num2str(x(1)),'_to_',num2str(x(2)),'.mat');
                    save(confile_1,'f_data');
                    confile_2=strcat(conpath,name,'_sensor8_CROPPED(RawData)_',num2str(x(1)),'_to_',num2str(x(2)),'.mat');
                    save(confile_2,'cropped_data')                   
                end
                 text=msgbox(' PREPROCESSING IS DONE, NOW YOU CAN UPDATE THE MASTER DATASHEET','Success');
                uiwait(text);
%                 close all;
        else 
            app.AutomaticButton==1  
            fs=1000;
                 d=designfilt('lowpassfir','FilterOrder',app.FilterOrderEditField.Value,'CutoffFrequency',app.CutoffFrequencyHzEditField.Value,'SampleRate',fs);
                 f_data=filtfilt(d,app.ft_signal.Value);
                 plot(app.UIAxes_2,f_data,'R'); 
                 axis(app.UIAxes_2, 'tight');
                 title(app.UIAxes_2,'Filtered output','FontWeight','bold');
                plot(app.UIAxes,app.ft_signal.Value);
                axis(app.UIAxes, 'tight');
           
       end
        end

        % Selection changed function: ChooseMethodButtonGroup
        function ChooseMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseMethodButtonGroup.SelectedObject;
            if app.ManualButton.Value==1
                app.WindowSizeinmsDropDown.Visible='off';
                app.ShiftinginmsDropDown.Visible='off';
                app.ThreholdcriteriaDropDown.Visible='off';
                app.ThresholdValueEditField.Visible='off';
                app.PreprocessthesignalButton.Visible='off';
                app.CropfromrawsignalButton.Visible='off';
                app.FetchFullSignalButton.Visible='off';
                app.FetchButton.Visible='off';
                app.SaveConcatenatedOutputButton.Visible='off'
            else app.AutomaticButton.Value==1 
                app.WindowSizeinmsDropDown.Visible='on';
                app.ShiftinginmsDropDown.Visible='on';
                app.ThreholdcriteriaDropDown.Visible='on';
                app.ThresholdValueEditField.Visible='on';
                app.PreprocessthesignalButton.Visible='on';
                app.CropfromrawsignalButton.Visible='on';
                app.FetchFullSignalButton.Visible='on';
                app.FetchButton.Visible='on';
                
                
            end
            
        end

        % Size changed function: ChooseMethodButtonGroup
        function ChooseMethodButtonGroupSizeChanged(app, event)
            position = app.ChooseMethodButtonGroup.Position;
            
        end

        % Value changed function: ThresholdValueEditField
        function ThresholdValueEditFieldValueChanged(app, event)
            value = app.ThresholdValueEditField.Value;
            
        end

        % Value changed function: ThreholdcriteriaDropDown
        function ThreholdcriteriaDropDownValueChanged(app, event)
            value = app.ThreholdcriteriaDropDown.Value;
            
        end

        % Button pushed function: PreprocessthesignalButton
        function PreprocessthesignalButtonPushed(app, event)
               if app.ThreholdcriteriaDropDown.Value== 'Hard Threshold'
                    if app.ChooseSensorDropDown.Value == 'Sensor 1'
%                        data=app.UITable.Data(:,1);
                        data=app.ft_signal.Value;
                       w= app.WindowSizeinmsDropDown.Value;
                        s=app.ShiftinginmsDropDown.Value;
                        shift=str2num(s);
                        win=str2num(w);
                        filepath='D:\event\app\';
                        th=app.ThresholdValueEditField.Value;
                        f_data=[];
                        event_id=[];
                        nm=[];
                        start_t=[];
                        end_t=[];
                        tb=[];
                        th_criteria=[];
                        th_value=[];
                        clas=[];
                        win_size=[];
                        slide=[];
                        Fname='D:\Master Datasheet_templet.xlsx';
                        l=length(data);
                        for k=1:shift:l-win+1
                            data_win=k:k+win-1;
                            event=data(data_win);
                            op=wthresh(event,'h',th);
                                if op == 0
                                    f_data=(f_data);
                                else
                                    win_s=num2str(win);
                                    k_s=num2str(k);
                                    th_s=num2str(th);
                                    n=split(app.FileEditField.Value,"\");
                                    name=char(n(end,end));
                                    path=strcat(filepath,name,'_',app.ChooseSensorDropDown.Value,'_','th_',th_s);
                                    mkdir(path);
                                    fpath=strcat(path,'\',name,'_','event','_',k_s,'_','hard_thresholdig','_',th_s,'_','window','_',win_s,'_','win_slide',s,'.mat');
                                    save(fpath,'event');
                                    f_data=[f_data;event];
                                    event_id=[event_id;k];
                                    nm=[nm;name];
                                    File_Name=string(nm);
                                    st=(event_id+app.start_time)./1000;
                                    start_t=[start_t;st];
                                    et=st+0.4;
                                    end_t=[end_t;et];                      
                                    thc=app.ThreholdcriteriaDropDown.Value;
                                    th_criteria=[th_criteria;thc];
                                    thv=app.ThresholdValueEditField.Value;
                                    th_value=[th_value;thv];
                                    ws=app.WindowSizeinmsDropDown.Value;
                                    win_size=[win_size;ws];
                                    cl=app.ClassDropDown.Value;
                                    clas=[clas;cl];
                                    sl=app.ShiftinginmsDropDown.Value;
                                    slide=[slide;sl];
                                    t=[File_Name event_id st et th_criteria th_value win_size slide clas];
                                    app.t.data=t;                               
                              
                                end
                                                        
                        end
                            check_file=exist('name');
                        if check_file==0
                            f = msgbox({'no event found with specified property';'try changing threshold value'}, 'Error','error');
                            uiwait(f);
                            
                        else check_file==1
                            conpath='D:\event\concatenated signal\';
                            mkdir(conpath);
                            confile=strcat(conpath,name,'_','sensor1','_','th_',th_s,'_','concatenated','.mat');
                             confile=app.concat_signal;
                             app.concat_val=f_data;
                            save(confile,'f_data');
                            app.NoofeventsEditField.Value=length(f_data)/win;
                            plot(app.UIAxes_2,f_data);
                            axis(app.UIAxes_2, 'tight');
                            title(app.UIAxes_2,'Concatenated Extracted Events','FontWeight','bold');
                        end
                elseif app.ChooseSensorDropDown.Value == 'Sensor 2'
                    data=app.ft_signal.Value;
                    w= app.WindowSizeinmsDropDown.Value;
                    win=str2num(w);
                    s=app.ShiftinginmsDropDown.Value;
                    shift=str2num(s);
                    filepath='D:\event\app\';
                    th=app.ThresholdValueEditField.Value;
                    f_data=[];
                    event_id=[];
                    nm=[];
                    start_t=[];
                    end_t=[];
                    tb=[];
                    th_criteria=[];
                    th_value=[];
                    clas=[];
                    win_size=[];
                    slide=[];
                    Fname='D:\Master Datasheet_templet.xlsx';
                    l=length(data);
                    for k=1:shift:l-win+1
                        data_win=k:k+win-1;
                        event=data(data_win);
                        op=wthresh(event,'h',th);
                            if op == 0
                                f_data=(f_data);
                            else
                                win_s=num2str(win);
                                k_s=num2str(k);
                                th_s=num2str(th);
                                n=split(app.FileEditField.Value,"\");
                                name=char(n(end,end));
                                path=strcat(filepath,name,'_',app.ChooseSensorDropDown.Value,'_','th_',th_s);
                                mkdir(path);
                                fpath=strcat(path,'\',name,'_','event','_',k_s,'_','hard_thresholdig','_',th_s,'_','window','_',win_s,'.mat');
                                save(fpath,'event');
                                f_data=[f_data;event];
                                event_id=[event_id;k];
                                nm=[nm;name];
                                File_Name=string(nm);
                                st=(event_id+app.start_time)./1000;
                                start_t=[start_t;st];
                                et=st+0.4;
                                end_t=[end_t;et];                      
                                thc=app.ThreholdcriteriaDropDown.Value;
                                th_criteria=[th_criteria;thc];
                                thv=app.ThresholdValueEditField.Value;
                                th_value=[th_value;thv];
                                ws=app.WindowSizeinmsDropDown.Value;
                                win_size=[win_size;ws];
                                cl=app.ClassDropDown.Value;
                                clas=[clas;cl];
                                sl=app.ShiftinginmsDropDown.Value;
                                slide=[slide;sl];
                                t=[File_Name event_id st et th_criteria th_value win_size slide clas];
                                app.t.data=t;
                            end   
                    end 
                        check_file=exist('name');
                        if check_file==0
                            f = msgbox({'no event found with specified property';'try changing threshold value'}, 'Error','error');
                            uiwait(f);
                            
                        else check_file==1
                            conpath='D:\event\concatenated signal\';
                            mkdir(conpath);
                            confile=strcat(conpath,name,'_','sensor2','_','th_',th_s,'_','concatenated','.mat');
                            save(confile,'f_data');
                            app.NoofeventsEditField.Value=length(f_data)/win;
                            plot(app.UIAxes_2,f_data);
                            axis(app.UIAxes_2, 'tight');
                            title(app.UIAxes_2,'Concatenated Extracted Events','FontWeight','bold');
                        end
                elseif app.ChooseSensorDropDown.Value == 'Sensor 3'
                    data=app.ft_signal.Value;
                    w= app.WindowSizeinmsDropDown.Value;
                    win=str2num(w);
                    s=app.ShiftinginmsDropDown.Value;
                    shift=str2num(s);
                    filepath='D:\event\app\';
                    th=app.ThresholdValueEditField.Value;
                    f_data=[];
                    event_id=[];
                    nm=[];
                    start_t=[];
                    end_t=[];
                    tb=[];
                    th_criteria=[];
                    th_value=[];
                    clas=[];
                    win_size=[];
                    slide=[];
                    Fname='D:\Master Datasheet_templet.xlsx';
                    l=length(data);
                    for k=1:shift:l-win+1
                        data_win=k:k+win-1;
                        event=data(data_win);
                        op=wthresh(event,'h',th);
                            if op == 0
                                f_data=(f_data);
                            else
                                win_s=num2str(win);
                                k_s=num2str(k);
                                th_s=num2str(th);
                                n=split(app.FileEditField.Value,"\");
                                name=char(n(end,end));
                                path=strcat(filepath,name,'_',app.ChooseSensorDropDown.Value,'_','th_',th_s);
                                mkdir(path);
                                fpath=strcat(path,'\',name,'_','event','_',k_s,'_','hard_thresholdig','_',th_s,'_','window','_',win_s,'.mat');
                                save(fpath,'event');
                                f_data=[f_data;event];
                                event_id=[event_id;k];
                                nm=[nm;name];
                                File_Name=string(nm);
                                st=(event_id+app.start_time)./1000;
                                start_t=[start_t;st];
                                et=st+0.4;
                                end_t=[end_t;et];                      
                                thc=app.ThreholdcriteriaDropDown.Value;
                                th_criteria=[th_criteria;thc];
                                thv=app.ThresholdValueEditField.Value;
                                th_value=[th_value;thv];
                                ws=app.WindowSizeinmsDropDown.Value;
                                win_size=[win_size;ws];
                                cl=app.ClassDropDown.Value;
                                clas=[clas;cl];
                                sl=app.ShiftinginmsDropDown.Value;
                                slide=[slide;sl];
                                t=[File_Name event_id st et th_criteria th_value win_size slide clas];
                                app.t.data=t;
                            end   
                    end
                        check_file=exist('name');
                        if check_file==0
                            f = msgbox({'no event found with specified property';'try changing threshold value'}, 'Error','error');
                            uiwait(f);
                            
                        else check_file==1
                            conpath='D:\event\concatenated signal\';
                            mkdir(conpath);
                            confile=strcat(conpath,name,'_','sensor3','_','th_',th_s,'_','concatenated','.mat');
                            save(confile,'f_data');
                            app.NoofeventsEditField.Value=length(f_data)/win;
                            plot(app.UIAxes_2,f_data);
                            axis(app.UIAxes_2, 'tight');
                            title(app.UIAxes_2,'Concatenated Extracted Events','FontWeight','bold');
                        end
                elseif app.ChooseSensorDropDown.Value == 'Sensor 4'
                    data=app.ft_signal.Value;
                    w= app.WindowSizeinmsDropDown.Value;
                    win=str2num(w);
                    s=app.ShiftinginmsDropDown.Value;
                    shift=str2num(s);
                    filepath='D:\event\app\';
                    th=app.ThresholdValueEditField.Value;
                    f_data=[];
                    event_id=[];
                    nm=[];
                    start_t=[];
                    end_t=[];
                    tb=[];
                    th_criteria=[];
                    th_value=[];
                    clas=[];
                    win_size=[];
                    slide=[];
                    Fname='D:\Master Datasheet_templet.xlsx';
                    l=length(data);
                    for k=1:shift:l-win+1
                        data_win=k:k+win-1;
                        event=data(data_win);
                        op=wthresh(event,'h',th);
                            if op == 0
                                f_data=(f_data);
                            else
                                win_s=num2str(win);
                                k_s=num2str(k);
                                th_s=num2str(th);
                                n=split(app.FileEditField.Value,"\");
                                name=char(n(end,end));
                                path=strcat(filepath,name,'_',app.ChooseSensorDropDown.Value,'_','th_',th_s);
                                mkdir(path);
                                fpath=strcat(path,'\',name,'_','event','_',k_s,'_','hard_thresholdig','_',th_s,'_','window','_',win_s,'.mat');
                                save(fpath,'event');
                                f_data=[f_data;event];
                                event_id=[event_id;k];
                                nm=[nm;name];
                                File_Name=string(nm);
                                st=(event_id+app.start_time)./1000;
                                start_t=[start_t;st];
                                et=st+0.4;
                                end_t=[end_t;et];                      
                                thc=app.ThreholdcriteriaDropDown.Value;
                                th_criteria=[th_criteria;thc];
                                thv=app.ThresholdValueEditField.Value;
                                th_value=[th_value;thv];
                                ws=app.WindowSizeinmsDropDown.Value;
                                win_size=[win_size;ws];
                                cl=app.ClassDropDown.Value;
                                clas=[clas;cl];
                                sl=app.ShiftinginmsDropDown.Value;
                                slide=[slide;sl];
                                t=[File_Name event_id st et th_criteria th_value win_size slide clas];
                                app.t.data=t;
                            end   
                    end 
                        check_file=exist('name');
                        if check_file==0
                            f = msgbox({'no event found with specified property';'try changing threshold value'}, 'Error','error');
                            uiwait(f);
                        else check_file==1
                            conpath='D:\event\concatenated signal\';
                            mkdir(conpath);
                            confile=strcat(conpath,name,'_','sensor4','_','th_',th_s,'_','concatenated','.mat');
                            save(confile,'f_data');
                            app.NoofeventsEditField.Value=length(f_data)/win;
                            plot(app.UIAxes_2,f_data);
                            axis(app.UIAxes_2, 'tight');
                            title(app.UIAxes_2,'Concatenated Extracted Events','FontWeight','bold');
                        end
                elseif app.ChooseSensorDropDown.Value == 'Sensor 5'
                    data=app.ft_signal.Value;
                    w= app.WindowSizeinmsDropDown.Value;
                    win=str2num(w);
                    s=app.ShiftinginmsDropDown.Value;
                    shift=str2num(s);
                    filepath='D:\event\app\';
                    th=app.ThresholdValueEditField.Value;
                    f_data=[];
                    event_id=[];
                    nm=[];
                    start_t=[];
                    end_t=[];
                    tb=[];
                    th_criteria=[];
                    th_value=[];
                    clas=[];
                    win_size=[];
                    slide=[];
                    Fname='D:\Master Datasheet_templet.xlsx';
                    l=length(data);
                    for k=1:shift:l-win+1
                        data_win=k:k+win-1;
                        event=data(data_win);
                        op=wthresh(event,'h',th);
                            if op == 0
                                f_data=(f_data);
                            else
                                win_s=num2str(win);
                                k_s=num2str(k);
                                th_s=num2str(th);
                                n=split(app.FileEditField.Value,"\");
                                name=char(n(end,end));
                                path=strcat(filepath,name,'_',app.ChooseSensorDropDown.Value,'_','th_',th_s);
                                mkdir(path);
                                fpath=strcat(path,'\',name,'_','event','_',k_s,'_','hard_thresholdig','_',th_s,'_','window','_',win_s,'.mat');
                                save(fpath,'event');
                                f_data=[f_data;event];
                                event_id=[event_id;k];
                                nm=[nm;name];
                                File_Name=string(nm);
                                st=(event_id+app.start_time)./1000;
                                start_t=[start_t;st];
                                et=st+0.4;
                                end_t=[end_t;et];                      
                                thc=app.ThreholdcriteriaDropDown.Value;
                                th_criteria=[th_criteria;thc];
                                thv=app.ThresholdValueEditField.Value;
                                th_value=[th_value;thv];
                                ws=app.WindowSizeinmsDropDown.Value;
                                win_size=[win_size;ws];
                                cl=app.ClassDropDown.Value;
                                clas=[clas;cl];
                                sl=app.ShiftinginmsDropDown.Value;
                                slide=[slide;sl];
                                t=[File_Name event_id st et th_criteria th_value win_size slide clas];
                                app.t.data=t;
                            end   
                    end 
                        check_file=exist('name');
                        if check_file==0
                            f = msgbox({'no event found with specified property';'try changing threshold value'}, 'Error','error');
                            uiwait(f);
                        else check_file==1
                            conpath='D:\event\concatenated signal\';
                            mkdir(conpath);
                            confile=strcat(conpath,name,'_','sensor5','_','th_',th_s,'_','concatenated','.mat');
                            save(confile,'f_data');
                            app.NoofeventsEditField.Value=length(f_data)/win;
                            plot(app.UIAxes_2,f_data);
                            axis(app.UIAxes_2, 'tight');
                            title(app.UIAxes_2,'Concatenated Extracted Events','FontWeight','bold');
                        end
                elseif app.ChooseSensorDropDown.Value == 'Sensor 6'
                    data=app.ft_signal.Value;
                    w= app.WindowSizeinmsDropDown.Value;
                    win=str2num(w);
                    s=app.ShiftinginmsDropDown.Value;
                    shift=str2num(s);
                    filepath='D:\event\app\';
                    th=app.ThresholdValueEditField.Value;
                    f_data=[];
                    event_id=[];
                    nm=[];
                    start_t=[];
                    end_t=[];
                    tb=[];
                    th_criteria=[];
                    th_value=[];
                    clas=[];
                    win_size=[];
                    slide=[];
                    Fname='D:\Master Datasheet_templet.xlsx';
                    l=length(data);
                    for k=1:shift:l-win+1
                        data_win=k:k+win-1;
                        event=data(data_win);
                        op=wthresh(event,'h',th);
                            if op == 0
                                f_data=(f_data);
                            else
                                win_s=num2str(win);
                                k_s=num2str(k);
                                th_s=num2str(th);
                                n=split(app.FileEditField.Value,"\");
                                name=char(n(end,end));
                                path=strcat(filepath,name,'_',app.ChooseSensorDropDown.Value,'_','th_',th_s);
                                mkdir(path);
                                fpath=strcat(path,'\',name,'_','event','_',k_s,'_','hard_thresholdig','_',th_s,'_','window','_',win_s,'.mat');
                                save(fpath,'event');
                                f_data=[f_data;event];
                                event_id=[event_id;k];
                                nm=[nm;name];
                                File_Name=string(nm);
                                st=(event_id+app.start_time)./1000;
                                start_t=[start_t;st];
                                et=st+0.4;
                                end_t=[end_t;et];                      
                                thc=app.ThreholdcriteriaDropDown.Value;
                                th_criteria=[th_criteria;thc];
                                thv=app.ThresholdValueEditField.Value;
                                th_value=[th_value;thv];
                                ws=app.WindowSizeinmsDropDown.Value;
                                win_size=[win_size;ws];
                                cl=app.ClassDropDown.Value;
                                clas=[clas;cl];
                                sl=app.ShiftinginmsDropDown.Value;
                                slide=[slide;sl];
                                t=[File_Name event_id st et th_criteria th_value win_size slide clas];
                                app.t.data=t;
                            end   
                    end 
                        check_file=exist('name');
                        if check_file==0
                            f = msgbox({'no event found with specified property';'try changing threshold value'}, 'Error','error');
                            uiwait(f);
                        else check_file==1
                            conpath='D:\event\concatenated signal\';
                            mkdir(conpath);
                            confile=strcat(conpath,name,'_','sensor6','_','th_',th_s,'_','concatenated','.mat');
                            save(confile,'f_data');
                            app.NoofeventsEditField.Value=length(f_data)/win;
                            plot(app.UIAxes_2,f_data);
                            axis(app.UIAxes_2, 'tight');
                            title(app.UIAxes_2,'Concatenated Extracted Events','FontWeight','bold');
                        end
                elseif app.ChooseSensorDropDown.Value == 'Sensor 7'
                    data=app.ft_signal.Value;
                    w= app.WindowSizeinmsDropDown.Value;
                    win=str2num(w);
                    s=app.ShiftinginmsDropDown.Value;
                    shift=str2num(s);
                    filepath='D:\event\app\';
                    th=app.ThresholdValueEditField.Value;
                    f_data=[];
                    event_id=[];
                    nm=[];
                    start_t=[];
                    end_t=[];
                    tb=[];
                    th_criteria=[];
                    th_value=[];
                    clas=[];
                    win_size=[];
                    slide=[];
                    Fname='D:\Master Datasheet_templet.xlsx';
                    l=length(data);
                    for k=1:shift:l-win+1
                        data_win=k:k+win-1;
                        event=data(data_win);
                        op=wthresh(event,'h',th);
                            if op == 0
                                f_data=(f_data);
                            else
                                win_s=num2str(win);
                                k_s=num2str(k);
                                th_s=num2str(th);
                                n=split(app.FileEditField.Value,"\");
                                name=char(n(end,end));
                                path=strcat(filepath,name,'_',app.ChooseSensorDropDown.Value,'_','th_',th_s);
                                mkdir(path);
                                fpath=strcat(path,'\',name,'_','event','_',k_s,'_','hard_thresholdig','_',th_s,'_','window','_',win_s,'.mat');
                                save(fpath,'event');
                                f_data=[f_data;event]; 
                                event_id=[event_id;k];
                                nm=[nm;name];
                                File_Name=string(nm);
                                st=(event_id+app.start_time)./1000;
                                start_t=[start_t;st];
                                et=st+0.4;
                                end_t=[end_t;et];                      
                                thc=app.ThreholdcriteriaDropDown.Value;
                                th_criteria=[th_criteria;thc];
                                thv=app.ThresholdValueEditField.Value;
                                th_value=[th_value;thv];
                                ws=app.WindowSizeinmsDropDown.Value;
                                win_size=[win_size;ws];
                                cl=app.ClassDropDown.Value;
                                clas=[clas;cl];
                                sl=app.ShiftinginmsDropDown.Value;
                                slide=[slide;sl];
                                t=[File_Name event_id st et th_criteria th_value win_size slide clas];
                                app.t.data=t;
                            end   
                    end 
                        check_file=exist('name');
                        if check_file==0
                            f = msgbox({'no event found with specified property';'try changing threshold value'}, 'Error','error');
                            uiwait(f);
                        else check_file==1
                            conpath='D:\event\concatenated signal\';
                            mkdir(conpath);
                            confile=strcat(conpath,name,'_','sensor7','_','th_',th_s,'_','concatenated','.mat');
                            save(confile,'f_data');
                            app.NoofeventsEditField.Value=length(f_data)/win;
                            plot(app.UIAxes_2,f_data);
                            axis(app.UIAxes_2, 'tight');
                            title(app.UIAxes_2,'Concatenated Extracted Events','FontWeight','bold');
                        end
                else data=app.ft_signal.Value;
                    w= app.WindowSizeinmsDropDown.Value;
                    win=str2num(w);
                    s=app.ShiftinginmsDropDown.Value;
                    shift=str2num(s);
                    filepath='D:\event\app\';
                    th=app.ThresholdValueEditField.Value;
                    f_data=[];
                    event_id=[];
                    nm=[];
                    start_t=[];
                    end_t=[];
                    tb=[];
                    th_criteria=[];
                    th_value=[];
                    clas=[];
                    win_size=[];
                    slide=[];
                    Fname='D:\Master Datasheet_templet.xlsx';
                    l=length(data);
                    for k=1:shift:l-win+1
                        data_win=k:k+win-1;
                        event=data(data_win);
                        op=wthresh(event,'h',th);
                            if op == 0
                                f_data=(f_data);
                            else
                                win_s=num2str(win);
                                k_s=num2str(k);
                                th_s=num2str(th);
                                n=split(app.FileEditField.Value,"\");
                                name=char(n(end,end));
                                path=strcat(filepath,name,'_',app.ChooseSensorDropDown.Value,'_','th_',th_s);
                                mkdir(path);
                                fpath=strcat(path,'\',name,'_','event','_',k_s,'_','hard_thresholdig','_',th_s,'_','window','_',win_s,'_','win_slide',s,'.mat');
                                save(fpath,'event');
                                f_data=[f_data;event];
                                event_id=[event_id;k];
                                nm=[nm;name];
                                File_Name=string(nm);
                                st=(event_id+app.start_time)./1000;
                                start_t=[start_t;st];
                                et=st+0.4;
                                end_t=[end_t;et];                      
                                thc=app.ThreholdcriteriaDropDown.Value;
                                th_criteria=[th_criteria;thc];
                                thv=app.ThresholdValueEditField.Value;
                                th_value=[th_value;thv];
                                ws=app.WindowSizeinmsDropDown.Value;
                                win_size=[win_size;ws];
                                cl=app.ClassDropDown.Value;
                                clas=[clas;cl];
                                sl=app.ShiftinginmsDropDown.Value;
                                slide=[slide;sl];
                                t=[File_Name event_id st et th_criteria th_value win_size slide clas];
                                app.t.data=t;
                            end   
                    end 
                        check_file=exist('name');
                        if check_file==0
                            f = msgbox({'no event found with specified property';'try changing threshold value'}, 'Error','error');
                            uiwait(f);
                        else check_file==1
                            conpath='D:\event\concatenated signal\';
                            mkdir(conpath);
                            confile=strcat(conpath,name,'_','sensor8','_','th_',th_s,'_','concatenated','.mat');
                            save(confile,'f_data');
                            app.NoofeventsEditField.Value=length(f_data)/win;
                            plot(app.UIAxes_2,f_data);
                            axis(app.UIAxes_2, 'tight');
                            title(app.UIAxes_2,'Concatenated Extracted Events','FontWeight','bold');
                        end
                        
                    end
                    
               end
    text=msgbox(' PREPROCESSING IS DONE, NOW YOU CAN UPDATE THE MASTER DATASHEET','Success');
    uiwait(text); 
%     close all;
        end

        % Value changed function: ClassDropDown
        function ClassDropDownValueChanged(app, event)
            value = app.ClassDropDown.Value;
            
        end

        % Value changed function: ShiftinginmsDropDown
        function ShiftinginmsDropDownValueChanged(app, event)
            value = app.ShiftinginmsDropDown.Value;
            
        end

        % Value changed function: WindowSizeinmsDropDown
        function WindowSizeinmsDropDownValueChanged(app, event)
            value = app.WindowSizeinmsDropDown.Value;
            
        end

        % Value changed function: FilterOrderEditField
        function FilterOrderEditFieldValueChanged(app, event)
            value = app.FilterOrderEditField.Value;
%             app.FilterOrderEditField.Value=forder;
        end

        % Value changed function: CutoffFrequencyHzEditField
        function CutoffFrequencyHzEditFieldValueChanged(app, event)
            value = app.CutoffFrequencyHzEditField.Value;
            
        end

        % Selection changed function: SignalFetchingButtonGroup
        function SignalFetchingButtonGroupSelectionChanged(app, event)
            selectedButton = app.SignalFetchingButtonGroup.SelectedObject;
            
            
        end

        % Button pushed function: FetchButton
        function FetchButtonPushed(app, event)
           if app.CropfromrawsignalButton.Value==1
               %cla(app.UIAxes_2);
                if app.ChooseSensorDropDown.Value=='Sensor 1'
                   f_signal=app.UITable.Data(:,1);
                elseif app.ChooseSensorDropDown.Value=='Sensor 2'
                   f_signal=app.UITable.Data(:,2);
                elseif app.ChooseSensorDropDown.Value=='Sensor 3'
                   f_signal=app.UITable.Data(:,3);
                elseif app.ChooseSensorDropDown.Value=='Sensor 4'
                   f_signal=app.UITable.Data(:,4);
                elseif app.ChooseSensorDropDown.Value=='Sensor 5'
                   f_signal=app.UITable.Data(:,5);
                elseif app.ChooseSensorDropDown.Value=='Sensor 6'
                   f_signal=app.UITable.Data(:,6);
                elseif app.ChooseSensorDropDown.Value=='Sensor 7'
                   f_signal=app.UITable.Data(:,7);
                else app.ChooseSensorDropDown.Value=='Sensor 8'
                   f_signal=app.UITable.Data(:,8);
                end
                   figure,plot(f_signal);
                   [x,y]=getpts;
                   start_tym=floor(x(1));
                   app.start_time=start_tym;
                   end_tym=floor(x(2));
                   fetch_signal=f_signal(start_tym:end_tym);
                   plot(app.UIAxes,fetch_signal);
                   close figure 1;
                   app.ft_signal.Value=fetch_signal;
           else app.FetchFullSignalButton.Value==1
               %cla(app.UIAxes_2);
               if app.ChooseSensorDropDown.Value=='Sensor 1'
                   fetch_signal=app.UITable.Data(:,1);
               elseif app.ChooseSensorDropDown.Value=='Sensor 2'
                   fetch_signal=app.UITable.Data(:,2);
               elseif app.ChooseSensorDropDown.Value=='Sensor 3'
                   fetch_signal=app.UITable.Data(:,3);
               elseif app.ChooseSensorDropDown.Value=='Sensor 4'
                   fetch_signal=app.UITable.Data(:,4);
               elseif app.ChooseSensorDropDown.Value=='Sensor 5'
                   fetch_signal=app.UITable.Data(:,5);
               elseif app.ChooseSensorDropDown.Value=='Sensor 6'
                   fetch_signal=app.UITable.Data(:,6);
               elseif app.ChooseSensorDropDown.Value=='Sensor 7'
                   fetch_signal=app.UITable.Data(:,7);
               else app.ChooseSensorDropDown.Value=='Sensor 8'
                   fetch_signal=app.UITable.Data(:,8);
               end
               plot(app.UIAxes,fetch_signal);
               app.ft_signal.Value=fetch_signal;
               app.start_time=[0];
                
           end
        end

        % Button pushed function: SaveConcatenatedOutputButton
        function SaveConcatenatedOutputButtonPushed(app, event)
            conval=app.concat_val;
            consig=app.concat_signal;
            save(consig,'conval');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FileEditFieldLabel = uilabel(app.UIFigure);
            app.FileEditFieldLabel.Text = 'File:';
            app.FileEditField = uieditfield(app.UIFigure, 'text');
            app.FileEditField.ValueChangedFcn = createCallbackFcn(app, @FileEditFieldValueChanged, true);
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.ClassDropDownLabel = uilabel(app.ParametersPanel);
            app.ClassDropDownLabel.Text = 'Class';
            app.ClassDropDown = uidropdown(app.ParametersPanel);
            app.ClassDropDown.Items = {'Elephant', 'Human', 'Deer', 'Train', 'Elk', 'Badger', 'Peacock', 'Wild Boar'};
            app.ClassDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassDropDownValueChanged, true);
            app.ClassDropDown.Value = 'Elephant';
            app.ShiftinginmsDropDownLabel = uilabel(app.ParametersPanel);
            app.ShiftinginmsDropDownLabel.Text = 'Shifting (in ms)';
            app.ShiftinginmsDropDown = uidropdown(app.ParametersPanel);
            app.ShiftinginmsDropDown.Items = {'25', '50', '75', '100'};
            app.ShiftinginmsDropDown.ValueChangedFcn = createCallbackFcn(app, @ShiftinginmsDropDownValueChanged, true);
            app.ShiftinginmsDropDown.Value = '25';
            app.WindowSizeinmsDropDownLabel = uilabel(app.ParametersPanel);
            app.WindowSizeinmsDropDownLabel.Text = 'Window Size(in ms)';
            app.WindowSizeinmsDropDown = uidropdown(app.ParametersPanel);
            app.WindowSizeinmsDropDown.Items = {'100', '200', '300', '400'};
            app.WindowSizeinmsDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowSizeinmsDropDownValueChanged, true);
            app.WindowSizeinmsDropDown.Value = '100';
            app.ThreholdcriteriaDropDownLabel = uilabel(app.ParametersPanel);
            app.ThreholdcriteriaDropDownLabel.Text = 'Threhold criteria ';
            app.ThreholdcriteriaDropDown = uidropdown(app.ParametersPanel);
            app.ThreholdcriteriaDropDown.Items = {'Hard Threshold', 'Moving avg.', 'SNR'};
            app.ThreholdcriteriaDropDown.ValueChangedFcn = createCallbackFcn(app, @ThreholdcriteriaDropDownValueChanged, true);
            app.ThreholdcriteriaDropDown.Value = 'Hard Threshold';
            app.ThresholdValueEditFieldLabel = uilabel(app.ParametersPanel);
            app.ThresholdValueEditFieldLabel.Text = 'Threshold Value';
            app.ThresholdValueEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ThresholdValueEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdValueEditFieldValueChanged, true);
            app.PreprocessthesignalButton = uibutton(app.UIFigure, 'push');
            app.PreprocessthesignalButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessthesignalButtonPushed, true);
            app.PreprocessthesignalButton.Text = 'Preprocess the signal';
            app.OutputHandlerPanel = uipanel(app.UIFigure);
            app.OutputHandlerPanel.Title = 'Output Handler';
            app.NoofeventsEditFieldLabel = uilabel(app.OutputHandlerPanel);
            app.NoofeventsEditFieldLabel.Text = 'No. of events';
            app.NoofeventsEditField = uieditfield(app.OutputHandlerPanel, 'numeric');
            app.NoofeventsEditField.ValueChangedFcn = createCallbackFcn(app, @NoofeventsEditFieldValueChanged, true);
            app.NoofeventsEditField.Editable = 'off';
            app.UpdatemasterdatasheetButton = uibutton(app.OutputHandlerPanel, 'push');
            app.UpdatemasterdatasheetButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatemasterdatasheetButtonPushed, true);
            app.UpdatemasterdatasheetButton.Text = 'Update master datasheet';
            app.ResetButton = uibutton(app.OutputHandlerPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveConcatenatedOutputButton = uibutton(app.OutputHandlerPanel, 'push');
            app.SaveConcatenatedOutputButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConcatenatedOutputButtonPushed, true);
            app.SaveConcatenatedOutputButton.Text = 'Save Concatenated Output';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Signal (Detrend)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Concatenated Extracted Events')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.OriginalSignalDetrendDataPointsLabel = uilabel(app.UIFigure);
            app.OriginalSignalDetrendDataPointsLabel.Text = 'Original Signal(Detrend) Data Points';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Sensor 1'; 'Sensor  2'; 'Sensor  3'; 'Sensor  4'; 'Sensor 5'; 'Sensor 6'; 'Sensor 7'; 'Sensor 8'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.LowpassFilterPanel = uipanel(app.UIFigure);
            app.LowpassFilterPanel.Title = 'Lowpass Filter';
            app.CutoffFrequencyHzEditFieldLabel = uilabel(app.LowpassFilterPanel);
            app.CutoffFrequencyHzEditFieldLabel.Text = 'Cutoff Frequency(Hz) :';
            app.CutoffFrequencyHzEditField = uieditfield(app.LowpassFilterPanel, 'numeric');
            app.CutoffFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequencyHzEditFieldValueChanged, true);
            app.FilterOrderEditFieldLabel = uilabel(app.LowpassFilterPanel);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order :';
            app.FilterOrderEditField = uieditfield(app.LowpassFilterPanel, 'numeric');
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterOrderEditFieldValueChanged, true);
            app.PlotSensorSpecificSignalandItsFilteredOPButton = uibutton(app.LowpassFilterPanel, 'push');
            app.PlotSensorSpecificSignalandItsFilteredOPButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSensorSpecificSignalandItsFilteredOPButtonPushed, true);
            app.PlotSensorSpecificSignalandItsFilteredOPButton.Text = 'Plot Sensor Specific Signal and Its Filtered O/P';
            app.ChooseMethodButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseMethodButtonGroupSelectionChanged, true);
            app.ChooseMethodButtonGroup.Title = 'Choose Method';
            app.ChooseMethodButtonGroup.SizeChangedFcn = createCallbackFcn(app, @ChooseMethodButtonGroupSizeChanged, true);
            app.AutomaticButton = uiradiobutton(app.ChooseMethodButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.AutomaticButton.Value = true;
            app.ManualButton = uiradiobutton(app.ChooseMethodButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.SignalFetchingButtonGroup = uibuttongroup(app.UIFigure);
            app.SignalFetchingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SignalFetchingButtonGroupSelectionChanged, true);
            app.SignalFetchingButtonGroup.Title = 'Signal Fetching';
            app.FetchFullSignalButton = uiradiobutton(app.SignalFetchingButtonGroup);
            app.FetchFullSignalButton.Text = 'Fetch Full Signal';
            app.FetchFullSignalButton.Value = true;
            app.CropfromrawsignalButton = uiradiobutton(app.SignalFetchingButtonGroup);
            app.CropfromrawsignalButton.Text = 'Crop from raw signal';
            app.FetchButton = uibutton(app.SignalFetchingButtonGroup, 'push');
            app.FetchButton.ButtonPushedFcn = createCallbackFcn(app, @FetchButtonPushed, true);
            app.FetchButton.Text = 'Fetch';
            app.ChooseSensorLabel = uilabel(app.SignalFetchingButtonGroup);
            app.ChooseSensorLabel.Text = 'Choose Sensor   :';
            app.ChooseSensorDropDown = uidropdown(app.SignalFetchingButtonGroup);
            app.ChooseSensorDropDown.Items = {'Sensor 1', 'Sensor 2', 'Sensor 3', 'Sensor 4', 'Sensor 5', 'Sensor 6', 'Sensor 7', 'Sensor 8'};
            app.ChooseSensorDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseSensorDropDownValueChanged, true);
            app.ChooseSensorDropDown.Value = 'Sensor 1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = preprocessing_UI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end